classdef MMF_DSCA_V1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SettingparameterTab             matlab.ui.container.Tab
        SettingparameterPanel           matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        filepathButton                  matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        DurationminEditField            matlab.ui.control.EditField
        DurationminEditFieldLabel       matlab.ui.control.Label
        StartingpointminEditField       matlab.ui.control.EditField
        StartingpointminEditFieldLabel  matlab.ui.control.Label
        MeasurementTimeminEditField     matlab.ui.control.NumericEditField
        MeasurementTimeminEditFieldLabel  matlab.ui.control.Label
        FilenameEditField_2             matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        ChannelDescriptionPanel         matlab.ui.container.Panel
        cEditField                      matlab.ui.control.EditField
        cEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.EditField
        bEditFieldLabel                 matlab.ui.control.Label
        gEditField                      matlab.ui.control.EditField
        gEditFieldLabel                 matlab.ui.control.Label
        rEditField                      matlab.ui.control.EditField
        rEditFieldLabel                 matlab.ui.control.Label
        DetailsettingPanel              matlab.ui.container.Panel
        ChannelEditField_3              matlab.ui.control.EditField
        ChannelEditField_3Label         matlab.ui.control.Label
        ExposuretimeusEditField         matlab.ui.control.NumericEditField
        ExposuretimeusEditFieldLabel    matlab.ui.control.Label
        FramerateFPSEditField_3         matlab.ui.control.NumericEditField
        FramerateFPSEditField_3Label    matlab.ui.control.Label
        runButton                       matlab.ui.control.Button
        GraphPlotTab                    matlab.ui.container.Tab
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        Windowsize10Button              matlab.ui.control.RadioButton
        Windowsize5Button_2             matlab.ui.control.RadioButton
        RawButton_2                     matlab.ui.control.RadioButton
        SettingPanel                    matlab.ui.container.Panel
        usernoteTextArea                matlab.ui.control.TextArea
        ExposureTimeEditField           matlab.ui.control.EditField
        ExposureTimeEditFieldLabel      matlab.ui.control.Label
        MeasurementtimeEditField        matlab.ui.control.EditField
        MeasurementtimeEditFieldLabel   matlab.ui.control.Label
        ChannelEditField_2              matlab.ui.control.EditField
        ChannelEditField_2Label         matlab.ui.control.Label
        GainRawEditField                matlab.ui.control.EditField
        GainRawEditFieldLabel           matlab.ui.control.Label
        FramerateFPSEditField_2         matlab.ui.control.EditField
        FramerateFPSEditField_2Label    matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % 파일을 읽는데 필요한 value

        file_save_path = ''; % Data save 할 경로
         
        index = ''; % import input이 잘 들어왔는지 확인

        file_path = ''; % Data file의 경로
        file_name = ''; % Data file의 이름
        readme_name = ''; % readme file의 경로 + 이름
        readme_lines = ''; % readme file의 text들을 각 라인씩 읽어 저장
        BFI_name = ''; % BFI file 경로 + 이름
        BFIdata; % BFI data
        % readme file에서 setting 값들을 읽어 저장한 value
        % setting_value(n, 1)은 parameter 이름이 저장되고, setting_value(n, 2)에 해당 parameter의 값이 저장
        % setting_value(2, 1) == 'Channel' & setting_value(2, 2) == 'rgbc'
        setting_value = [];
        filterBFI = [];
   
        user_note = 0; % '==user note=='가 시작하는 line의 index

        % color_value는 legend에도 쓰기 때문에 color_value를 뽑아내 legend에 맞는 형식으로 바꾸는 함수를 따로 만들었다.
        color_value; %color_value를 char 형태로 저장
        color_label = ''; %color_value의 길이와 type을 바꿔서 저장
        
        % graph plot에 관한 값들
        FPS = 0; % setting_value에 뽑아낸 값으로 type은 double이다.
        Measurement_time = 0;
        str_pioint = 0;
        duration = 0;
        min = 60; % 1분을 의미
        x = 0; % x축 값을 의미

        % Setting Parameter properties
        channel_color = '';
    end
    
   
    methods (Access = public)
                   
        % Window size graph plot function
        function plotgraph_size(app, color, data, x, size)

            % filter
            b = 1/size*ones(1,size);
            filtBFI = filter(b,1,data);
            app.filterBFI = filtBFI;
            
            hold(app.UIAxes, "on");
                
            for i = 1:length(color) - 1
                plot(app.UIAxes, x, filtBFI(:,i), color(i));
                
                
            end
            
            if app.str_pioint ~= 0
                xline(app.UIAxes, [app.str_pioint app.str_pioint + app.duration]);
            end
            xlim(app.UIAxes,[0 x(end)]);
        end    

       function text_mean_std(app)
            
            Mean_BFI = 0;
            Std_BFI = 0;
            Mean_BFI = round(mean(app.filterBFI));
            Std_BFI = round(std(app.filterBFI));

            [ROW,COL] = size(app.filterBFI);

            for i = 1:COL
                str_str = strcat(num2str(Mean_BFI(i)),' ± ' ,num2str(Std_BFI(i)));
                text(app.UIAxes, (ROW/(app.FPS*60)) * 1.04, max(max(app.filterBFI))*(0.7-0.1*(i-1)), str_str, "Color", app.color_value(i), "FontSize",20);
            end

        end


        % readme.txt에 있는, 우리가 알고 싶어 하는 값들을 n*2 string으로 저장하는 함수
        function separable(app, readme)
            
            % readme file line의 총 개수
            l_tot = length(readme);

            for i = 1:l_tot
                
                % 문자열에 인덱싱을 하기 위해 각 line을 char로 바꾼다.
                line_i = convertStringsToChars(readme(i));

                % ' = ' 를 읽어 전 후를 구분한다. ('='전: parameter name/'=' 후: parameter value)
                lr = regexp(readme(i), ' = ');
                
                if lr ~= 0 % 1r이 0이 아닌 경우 = 그 line에 '='가 존재하는 경우
                    
                    %setting_value에 왼쪽setting_value(n, 1)은 parameter name, 오른쪽setting_value(n, 2)은 parameter value를 저장한다.
                    app.setting_value = [[string(line_i(1:lr-1)) string(line_i(lr+3:length(line_i)))]; app.setting_value]; 
                

                % "==user note==" 출력
                elseif line_i == "==User Note=="
                    app.user_note = i;
                    app.usernoteTextArea.Value = line_i;

                     % "==user note==" 아래의 line 모두 출력
                elseif i > app.user_note
                    app.usernoteTextArea.Value = [app.usernoteTextArea.Value; line_i];
                end  
            end
        end  

        % Raidobutton에 관한 코드
        function raidobutton(app, selectedButton)

            % Raw를 눌렸을 때
            if selectedButton == app.RawButton_2
                clear legend
                cla(app.UIAxes,"reset");
                title(app.UIAxes, string(app.readme_lines(1)));
                plotgraph_size(app, app.color_value, app.BFIdata, app.x, 1)
                text_mean_std(app)
                legend(app.UIAxes, {app.color_value(:)}, 'Location','northeastoutside');

            % Windowsize 5를 눌렸을 때
            elseif selectedButton == app.Windowsize5Button_2
                clear legend
                cla(app.UIAxes,"reset");
                title(app.UIAxes, string(app.readme_lines(1)));
                plotgraph_size(app, app.color_value, app.BFIdata, app.x, 5);
                text_mean_std(app)
                legend(app.UIAxes, {app.color_value(:)}, 'Location','northeastoutside');

            % Windowsize 10을 눌렸을 때
            elseif selectedButton == app.Windowsize10Button
                clear legend
                cla(app.UIAxes,"reset");
                title(app.UIAxes, string(app.readme_lines(1)));
                plotgraph_size(app, app.color_value, app.BFIdata, app.x, 10)
                text_mean_std(app)
                legend(app.UIAxes, {app.color_value(:)}, 'Location','northeastoutside');
            end
        end
        
        % readme file의 값들로 text box를 채우고 오류 발생 시 warning window를 plot하는 함수
        function readme_function(app, setting)
            [ROW,COL] = size(app.BFIdata);
          
            
            for i = 1:length(setting) % setting_value 전체를 훓는다.
                
                % "parameter name"을 찾으면 해당 "parameter value"를 바로 출력
                                    
                if convertStringsToChars(setting(i, 1)) == "Channel"
                    app.ChannelEditField_2.Value = setting(i, 2);
                    app.color_value = convertStringsToChars(app.setting_value(i, 2));
                    app.color_label = app.color_value(1:length(app.color_value)-1);
                                                                          
                elseif convertStringsToChars(setting(i, 1)) == "Exposure Time"
                    app.ExposureTimeEditField.Value = setting(i, 2);   
                
                elseif convertStringsToChars(setting(i, 1)) == "GainRaw"
                    app.GainRawEditField.Value = setting(i, 2);
                    
                    %app.Test2EditField.Value = string(i);
                
                % FPS는 x축을 구성하는데 사용하므로 app.FPS에 double type으로 저장 후 다시 string으로 바꿔서 text box에 출력
                elseif convertStringsToChars(setting(i, 1)) == "Acq Frame Rate"
                    app.FPS = str2double(erase(setting(i, 2), 'Hz'));

                    % 단인 "Hz"도 달아준다.
                    app.FramerateFPSEditField_2.Value = string(app.FPS) + " Hz";

                elseif convertStringsToChars(setting(i, 1)) == "starting stimulus time"
                    app.str_pioint = str2double(erase(setting(i, 2), 'min'));
                 
                elseif convertStringsToChars(setting(i, 1)) == "duration time"
                    app.duration = str2double(erase(setting(i, 2), 'min'));

                end
            end
            app.MeasurementtimeEditField.Value = num2str(ROW/(app.FPS*60)); 
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % Data clear

            % value reset
            cla(app.UIAxes,"reset");
            app.file_path = '';
            app.file_name = '';
            app.readme_name = '';
            app.readme_lines = '';
            app.BFI_name = '';
            app.BFIdata;
            app.setting_value = [];
            app.color_value;
            app.color_label = "";
            app.FPS = 0;
            app.min = 60;
            app.x = 0;
            app.index;
            app.user_note = 10000;
            app.str_pioint = 0;
            app.duration = 0;
            
            % Textbox reset
            app.FilenameEditField.Value = '';
            app.FramerateFPSEditField_2.Value = '';
            app.ChannelEditField_2.Value = '';
            app.GainRawEditField.Value = '';
            app.MeasurementtimeEditField.Value = '';
            app.ExposureTimeEditField.Value = '';
            app.usernoteTextArea.Value = '';

            %RaidoButton reset
            app.FilterButtonGroup.SelectedObject = app.RawButton_2;
            %% 데이터를 불러와서 변수에 저장하는 과정                 

            [app.file_name,app.file_path, app.index] = uigetfile('*.mat');

            if app.index == 0
                 return
            end

            % data파일과 readme 파일의 경로를 각각 변수에 저장
            app.BFI_name = string([app.file_path app.file_name]);
            app.readme_name = strcat(app.file_path, "readme.txt");
            
            % 파일 이름을 출력
            app.FilenameEditField.Value = app.readme_name;
            
            % readlines()는 텍스트 파일 line을 읽어 string으로 반환하는 함수 
            app.readme_lines = readlines(app.readme_name);

            % importdata()는 파일을 불러와 배열을 반환하는 함수
            app.BFIdata = importdata(app.BFI_name);
                                    
            % 필요한 data를 뽑아내는 과정
            separable(app, app.readme_lines)
                        
            %Title
            title(app.UIAxes, string(app.readme_lines(1)));

            %Label & FontSize
            app.UIAxes.FontSize = 12;
            xlabel(app.UIAxes, "Time (min)", 'Fontsize', 10);
            ylabel(app.UIAxes, "BFI (A.U.)", 'Fontsize', 10);

            % Setting text box 채우기 및 오류 출력
            readme_function(app, app.setting_value)
                     
            % Setting text box에서 저장한 app.FPS로 app.x 계산
            app.x = (1:length(app.BFIdata))/(app.min * app.FPS);
            
            % default로 Raw graph 출력
            clear legend
            plotgraph_size(app, app.color_value, app.BFIdata, app.x, 1)
            text_mean_std(app)
            legend(app.UIAxes, {app.color_value(:)}, 'Location','northeastoutside');

        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            % Radiobutton group에서 조작한 결과를 selectedButton에 저장
            selectedButton = app.FilterButtonGroup.SelectedObject;

            % file이 들어오지 않았을 때 button을 조작해도 graph plot이 되지 않음
            % graph는 plot 되지 않았지만 legend가 뜨는 문제가 있어서 수정 함
            if app.index == 0
                return
            else
                %측정한 데이터를 읽어 그래프를 plot하는 과정
                raidobutton(app, selectedButton)
            end
        end

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            B = struct('savepath',app.file_save_path,...
                       'Filename',app.FilenameEditField_2.Value,...
                       'Measurement_time',app.MeasurementTimeminEditField.Value,...
                       'Frame_rate',app.FramerateFPSEditField_3.Value,...
                       'Exposure_time',app.ExposuretimeusEditField.Value, ...
                       'Channel',app.ChannelEditField_3.Value,...
                       'strpoint',app.StartingpointminEditField.Value,...
                       'duration',app.DurationminEditField.Value);
                     
            B.ChanDesc = {app.rEditField.Value, app.gEditField.Value, app.bEditField.Value, app.cEditField.Value};
            takeBFI_v1_2(B)
        end

        % Button pushed function: filepathButton
        function filepathButtonPushed(app, event)
            app.file_save_path = '';
            app.EditField.Value = '';
            app.file_save_path = uigetdir;
            app.EditField.Value = app.file_save_path;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SettingparameterTab = uitab(app.TabGroup);
            app.SettingparameterTab.Title = 'Setting parameter';
            app.runButton = uibutton(app.SettingparameterTab, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.DetailsettingPanel = uipanel(app.SettingparameterTab);
            app.DetailsettingPanel.Title = 'Detail setting';
            app.FramerateFPSEditField_3Label = uilabel(app.DetailsettingPanel);
            app.FramerateFPSEditField_3Label.Text = 'Frame rate (FPS)';
            app.FramerateFPSEditField_3 = uieditfield(app.DetailsettingPanel, 'numeric');
            app.FramerateFPSEditField_3.Value = 60;
            app.ExposuretimeusEditFieldLabel = uilabel(app.DetailsettingPanel);
            app.ExposuretimeusEditFieldLabel.Text = 'Exposure time (us)';
            app.ExposuretimeusEditField = uieditfield(app.DetailsettingPanel, 'numeric');
            app.ExposuretimeusEditField.Value = 2000;
            app.ChannelEditField_3Label = uilabel(app.DetailsettingPanel);
            app.ChannelEditField_3Label.Text = 'Channel';
            app.ChannelEditField_3 = uieditfield(app.DetailsettingPanel, 'text');
            app.ChannelEditField_3.Value = 'rgbc';
            app.SettingparameterPanel = uipanel(app.SettingparameterTab);
            app.SettingparameterPanel.Title = 'Setting parameter';
            app.ChannelDescriptionPanel = uipanel(app.SettingparameterPanel);
            app.ChannelDescriptionPanel.Title = 'Channel Description';
            app.rEditFieldLabel = uilabel(app.ChannelDescriptionPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.ChannelDescriptionPanel, 'text');
            app.gEditFieldLabel = uilabel(app.ChannelDescriptionPanel);
            app.gEditFieldLabel.Text = 'g';
            app.gEditField = uieditfield(app.ChannelDescriptionPanel, 'text');
            app.bEditFieldLabel = uilabel(app.ChannelDescriptionPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.ChannelDescriptionPanel, 'text');
            app.cEditFieldLabel = uilabel(app.ChannelDescriptionPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.ChannelDescriptionPanel, 'text');
            app.FilenameEditField_2Label = uilabel(app.SettingparameterPanel);
            app.FilenameEditField_2Label.Text = 'File name';
            app.FilenameEditField_2 = uieditfield(app.SettingparameterPanel, 'text');
            app.FilenameEditField_2.Value = 'test';
            app.MeasurementTimeminEditFieldLabel = uilabel(app.SettingparameterPanel);
            app.MeasurementTimeminEditFieldLabel.Text = 'Measurement Time (min)';
            app.MeasurementTimeminEditField = uieditfield(app.SettingparameterPanel, 'numeric');
            app.MeasurementTimeminEditField.Value = 3;
            app.Panel = uipanel(app.SettingparameterPanel);
            app.Panel.Title = 'Panel';
            app.StartingpointminEditFieldLabel = uilabel(app.Panel);
            app.StartingpointminEditFieldLabel.Text = 'Starting point (min)';
            app.StartingpointminEditField = uieditfield(app.Panel, 'text');
            app.DurationminEditFieldLabel = uilabel(app.Panel);
            app.DurationminEditFieldLabel.Text = 'Duration (min)';
            app.DurationminEditField = uieditfield(app.Panel, 'text');
            app.filepathButton = uibutton(app.SettingparameterPanel, 'push');
            app.filepathButton.ButtonPushedFcn = createCallbackFcn(app, @filepathButtonPushed, true);
            app.filepathButton.Text = 'file path';
            app.EditField = uieditfield(app.SettingparameterPanel, 'text');
            app.GraphPlotTab = uitab(app.TabGroup);
            app.GraphPlotTab.Title = 'Graph Plot';
            app.UIAxes = uiaxes(app.GraphPlotTab);
            zlabel(app.UIAxes, 'Z')
            app.ImportButton = uibutton(app.GraphPlotTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameEditFieldLabel = uilabel(app.GraphPlotTab);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.GraphPlotTab, 'text');
            app.SettingPanel = uipanel(app.GraphPlotTab);
            app.SettingPanel.Title = 'Setting';
            app.FramerateFPSEditField_2Label = uilabel(app.SettingPanel);
            app.FramerateFPSEditField_2Label.Text = 'Frame rate(FPS)';
            app.FramerateFPSEditField_2 = uieditfield(app.SettingPanel, 'text');
            app.GainRawEditFieldLabel = uilabel(app.SettingPanel);
            app.GainRawEditFieldLabel.Text = 'Gain Raw';
            app.GainRawEditField = uieditfield(app.SettingPanel, 'text');
            app.ChannelEditField_2Label = uilabel(app.SettingPanel);
            app.ChannelEditField_2Label.Text = 'Channel';
            app.ChannelEditField_2 = uieditfield(app.SettingPanel, 'text');
            app.MeasurementtimeEditFieldLabel = uilabel(app.SettingPanel);
            app.MeasurementtimeEditFieldLabel.Text = 'Measurement time';
            app.MeasurementtimeEditField = uieditfield(app.SettingPanel, 'text');
            app.ExposureTimeEditFieldLabel = uilabel(app.SettingPanel);
            app.ExposureTimeEditFieldLabel.Text = 'Exposure Time';
            app.ExposureTimeEditField = uieditfield(app.SettingPanel, 'text');
            app.usernoteTextArea = uitextarea(app.SettingPanel);
            app.FilterButtonGroup = uibuttongroup(app.GraphPlotTab);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.RawButton_2 = uiradiobutton(app.FilterButtonGroup);
            app.RawButton_2.Text = 'Raw';
            app.RawButton_2.Value = true;
            app.Windowsize5Button_2 = uiradiobutton(app.FilterButtonGroup);
            app.Windowsize5Button_2.Text = 'Window size 5';
            app.Windowsize10Button = uiradiobutton(app.FilterButtonGroup);
            app.Windowsize10Button.Text = 'Window size 10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MMF_DSCA_V1_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end